classdef path_create_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        StatusLabel         matlab.ui.control.Label
        ExportButton        matlab.ui.control.Button
        ValidateButton      matlab.ui.control.Button
        LSY2EditField       matlab.ui.control.NumericEditField
        Y2EditFieldLabel    matlab.ui.control.Label
        LSX2EditField       matlab.ui.control.NumericEditField
        X2EditFieldLabel    matlab.ui.control.Label
        LSY1EditField       matlab.ui.control.NumericEditField
        Y1EditFieldLabel    matlab.ui.control.Label
        LSX1EditField       matlab.ui.control.NumericEditField
        X1EditFieldLabel    matlab.ui.control.Label
        CreateLS            matlab.ui.control.Button
        LineSegmentLabel_2  matlab.ui.control.Label
        CreatePoint         matlab.ui.control.Button
        PointYEditField     matlab.ui.control.NumericEditField
        YEditFieldLabel     matlab.ui.control.Label
        PointXEditField     matlab.ui.control.NumericEditField
        XEditFieldLabel     matlab.ui.control.Label
        PointLabel          matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Frame 
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function update_point(~, ax, point)
            point.Pos = ax.CurrentPoint(1, 1:2);
        end
        
        function redraw_poly(~, axh, varargin)
            % pasted from draggableDemo
            % works for one linesegment, but not two

            % Deleting the previous polygon
            delete(getappdata(axh, "ls"));
            
            % Retrieving the vertex vector and corresponding xdata and ydata
            points = getappdata(axh, "points");
            xdata = cell2mat(get(points, "xdata"));
            ydata = cell2mat(get(points, "ydata"));
            
            % Plotting the new polygon and saving its handle as application data
            ls = plot(axh, [xdata.' xdata(1)],[ydata.' ydata(1)], "Color", "k");
            setappdata(axh, "ls", ls);
            
            % Putting the vertices on top of the polygon so that they are easier to drag (or
            % else, the polygone line get in the way)
             set(axh, "Children", [points ls]);
            
        end
        
        function redraw_ls_frompoint(~, ax, pointplot, endp, lineplot, num)
            % if linesegment is dragged by the point
            % update endp position, redraw line
            % is the linesegment a draggable or a line?
            endp.Pos = ax.CurrentPoint(1,1:2);
            oldX = lineplot.XData;
            oldY = lineplot.YData;
            if num == 1 
                % p1 was dragged

                % if lineplot is a line
                lineplot.XData = [endp.Pos(1) oldX(2)];
                lineplot.YData = [endp.Pos(2) oldY(2)];

                % if lineplot is a draggable
%                 lineplot.set_position([endp.Pos(1) oldX(2) ; ...
%                                        endp.Pos(2) oldY(2)])
            elseif num == 2
                % p2 was dragged

                % if lineplot is a line
                lineplot.XData = [oldX(1) endp.Pos(1)];
                lineplot.YData = [oldY(1) endp.Pos(2)];

                % if lineplot is a draggable
%                 lineplot.set_position([oldX(1) endp.Pos(1) ; ...
%                                        oldY(1) endp.Pos(2)])
            else
                error("Specify which endpoint was dragged with 1 or 2")
            end

%             set(ax, "Children", [pointplot lineplot]);
            
        end
        
        function redraw_ls_fromline(~, ax, p1, p2, line, lineplot)
            % if linesegment is dragged by the line
            % update both endp positions
            % draggable objects can set_position
            
            newX = lineplot.XData;
            newY = lineplot.YData;
            line.P1.Pos = [newX(1) newY(1)];
            line.P2.Pos = [newX(2) newY(2)];

            p1.XData = newX(1);
            p1.YData = newY(1);
            p2.XData = newX(2);
            p2.YData = newY(2);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Frame = Frame;
            
            ax = app.UIAxes();
            axis(ax, "equal");
            set(ax, "DataAspectRatio", [1 1 1], "Xlim", [-2 2], "YLim", [-2 2]);
            hold(ax, "on")
        end

        % Button pushed function: CreatePoint
        function CreatePointPushed(app, event)
            x = app.PointXEditField;
            y = app.PointYEditField;
            p = Point(app.Frame, x, y);

            plotp = plot(app.UIAxes, x, y);
            draggable(plotp, @(g)update_point(app, app.UIAxes, p));

            % for visible vertices
            set(plotp, "Marker", "o", "MarkerSize", 10, "MarkerFaceColor", "b", ...
            "MarkerEdgeColor", "none");
            
        end

        % Button pushed function: CreateLS
        function CreateLSButtonPushed(app, event)
            ax = app.UIAxes;
            
            x1 = app.LSX1EditField;
            y1 = app.LSY1EditField;
            x2 = app.LSX2EditField;
            y2 = app.LSY2EditField;
            l = LineSegment(app.Frame, x1, y1, x2, y2);

            p1 = plot(ax, x1, y1);
            p2 = plot(ax, x2, y2);
            points = [p1 p2];

            % for visible vertices
            set(points, "Marker", "o", "MarkerSize", 10, "MarkerFaceColor", "b", ...
            "MarkerEdgeColor", "none");
            
            % draggable line segment
            lineplot = line(ax, [x1 x2], [y1 y2]);
            set(lineplot, "Color", "m", "LineWidth", 2);
            

%             setappdata(ax, "points", points);
%             setappdata(ax, "ls", []);


            draggable(p1, @(g)redraw_ls_frompoint(app, ax, p1, l.P1, lineplot, 1));
            draggable(p2, @(g)redraw_ls_frompoint(app, ax, p2, l.P2, lineplot, 2));
            draggable(lineplot, @(g)redraw_ls_fromline(app, ax, p1, p2, l, lineplot));

%             redraw_ls(app, ax);
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            try 
                app.Frame.validate;
                app.StatusLabel.Text = 'Success!';
            catch 
                warning("Path validation was unsuccessful")
                app.StatusLabel.Text = 'Path validation was unsuccessful';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PointLabel = uilabel(app.UIFigure);
            app.PointLabel.Text = 'Point';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.PointXEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.PointYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreatePoint = uibutton(app.UIFigure, 'push');
            app.CreatePoint.ButtonPushedFcn = createCallbackFcn(app, @CreatePointPushed, true);
            app.CreatePoint.Text = 'Create';
            app.LineSegmentLabel_2 = uilabel(app.UIFigure);
            app.LineSegmentLabel_2.Text = 'Line Segment';
            app.CreateLS = uibutton(app.UIFigure, 'push');
            app.CreateLS.ButtonPushedFcn = createCallbackFcn(app, @CreateLSButtonPushed, true);
            app.CreateLS.Text = 'Create';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.LSX1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.LSY1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.LSX2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LSX2EditField.Value = 1;
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.LSY2EditField = uieditfield(app.UIFigure, 'numeric');
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = path_create_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end